classdef MainScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        c3dEditorUIFigure     matlab.ui.Figure
        c3dMATLABEditorLabel  matlab.ui.control.Label
        LoadandassignPanel    matlab.ui.container.Panel
        Loadc3dButton         matlab.ui.control.Button
    end

    properties (Access = private)
        controller;
        
        sensorDropDown;
        locationDropDown;
    end
    
    methods
        function addAssignmentLayout(app, sensorsNumber)
            % Add a dropdown for the sensors
            sensorList = zeros(1, sensorsNumber);
            for i = 1:sensorsNumber 
                sensorList(i) = i;
            end
            app.sensorDropDown = uidropdown(app.LoadandassignPanel, "Items", sensorList, "Position", [80, 70, 100, 22]);
            % Add a dropdown for the locations
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, controller)
            app.controller = controller;
        end

        % Button pushed function: Loadc3dButton
        function Loadc3dButtonPushed(app, event)
            app.controller.loadc3d();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.c3dEditorUIFigure = uifigure('Visible', 'off');
            app.c3dEditorUIFigure.Name = 'c3dEditor';
            app.c3dMATLABEditorLabel = uilabel(app.c3dEditorUIFigure);
            app.c3dMATLABEditorLabel.Text = '.c3d MATLAB Editor';
            app.LoadandassignPanel = uipanel(app.c3dEditorUIFigure);
            app.LoadandassignPanel.Title = 'Load and assign';
            app.Loadc3dButton = uibutton(app.LoadandassignPanel, 'push');
            app.Loadc3dButton.ButtonPushedFcn = createCallbackFcn(app, @Loadc3dButtonPushed, true);
            app.Loadc3dButton.Text = 'Load .c3d';
            % Show the figure after all components are created
            app.c3dEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainScreen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.c3dEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.c3dEditorUIFigure)
        end
    end
end